classdef Project_1_DSBFC_Final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Trimester6Label                 matlab.ui.control.Label
        GroupProjectLabel               matlab.ui.control.Label
        AnalogCommunicationLabel        matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        MadebyPanel                     matlab.ui.container.Panel
        AbhijeetTakleLabel              matlab.ui.control.Label
        MayannkKumarSinghLabel          matlab.ui.control.Label
        PrakharSinghRajputLabel         matlab.ui.control.Label
        PC41Label                       matlab.ui.control.Label
        PC33Label                       matlab.ui.control.Label
        PC35Label                       matlab.ui.control.Label
        SpectrumButton                  matlab.ui.control.Button
        Simulate                        matlab.ui.control.Button
        SignalsSelectorButtonGroup      matlab.ui.container.ButtonGroup
        DemodulatedSignal               matlab.ui.control.RadioButton
        ModulatedSignal                 matlab.ui.control.RadioButton
        CSignal                         matlab.ui.control.RadioButton
        MSignal                         matlab.ui.control.RadioButton
        InputPanel                      matlab.ui.container.Panel
        Fm                              matlab.ui.control.NumericEditField
        MessageFrequenceHzLabel         matlab.ui.control.Label
        Fc                              matlab.ui.control.NumericEditField
        CarrierFrequenceHzEditFieldLabel  matlab.ui.control.Label
        M                               matlab.ui.control.NumericEditField
        ModulationIndexmEditFieldLabel  matlab.ui.control.Label
        DSBFCGenerationandDetectionLabel  matlab.ui.control.Label
        Axis1                           matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Simulate
        function SimulateButtonPushed(app, event)
            assignin('base','Fm',app.Fm.Value);
            assignin('base','Fc',app.Fc.Value);
            assignin('base','M',app.M.Value);
            
            simout = sim('fullmodel');
            if(app.MSignal.Value == 1)
               plot(app.Axis1,simout.Fmdata.time,simout.Fmdata.Data);
               title(app.Axis1,'Message Signal');
            
            elseif(app.CSignal.Value == 1)
               plot(app.Axis1,simout.Fcdata.time,simout.Fcdata.Data);
               title(app.Axis1,'Carrier Signal');
            
            elseif(app.ModulatedSignal.Value == 1)
               plot(app.Axis1,simout.Modulateddata.time,simout.Modulateddata.Data);
               title(app.Axis1,'Modulated Signal');
            
            elseif(app.DemodulatedSignal.Value == 1)
               plot(app.Axis1,simout.Demodulateddata.time,simout.Demodulateddata.Data);
               title(app.Axis1,'Demodulated Signal');
            end
            
        end

        % Button pushed function: SpectrumButton
        function SpectrumButtonPushed(app, event)
            assignin('base','Fm',app.Fm.Value);
            assignin('base','Fc',app.Fc.Value);
            assignin('base','M',app.M.Value);
            
            sim('spectrum');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Axis1 = uiaxes(app.UIFigure);
            title(app.Axis1, 'Title')
            xlabel(app.Axis1, 'Time (sec)')
            ylabel(app.Axis1, 'Amplitude')
            zlabel(app.Axis1, 'Z')
            app.DSBFCGenerationandDetectionLabel = uilabel(app.UIFigure);
            app.DSBFCGenerationandDetectionLabel.Text = 'DSB-FC Generation and Detection';
            app.InputPanel = uipanel(app.UIFigure);
            app.InputPanel.Title = 'Input';
            app.ModulationIndexmEditFieldLabel = uilabel(app.InputPanel);
            app.ModulationIndexmEditFieldLabel.Text = 'Modulation Index (m)';
            app.M = uieditfield(app.InputPanel, 'numeric');
            app.CarrierFrequenceHzEditFieldLabel = uilabel(app.InputPanel);
            app.CarrierFrequenceHzEditFieldLabel.Text = 'Carrier Frequence (Hz)';
            app.Fc = uieditfield(app.InputPanel, 'numeric');
            app.MessageFrequenceHzLabel = uilabel(app.InputPanel);
            app.MessageFrequenceHzLabel.Text = 'Message Frequence (Hz)';
            app.Fm = uieditfield(app.InputPanel, 'numeric');
            app.SignalsSelectorButtonGroup = uibuttongroup(app.UIFigure);
            app.SignalsSelectorButtonGroup.Title = 'Signals Selector';
            app.MSignal = uiradiobutton(app.SignalsSelectorButtonGroup);
            app.MSignal.Text = 'Message signal';
            app.MSignal.Value = true;
            app.CSignal = uiradiobutton(app.SignalsSelectorButtonGroup);
            app.CSignal.Text = 'Carrier signal';
            app.ModulatedSignal = uiradiobutton(app.SignalsSelectorButtonGroup);
            app.ModulatedSignal.Text = 'Modulated signal';
            app.DemodulatedSignal = uiradiobutton(app.SignalsSelectorButtonGroup);
            app.DemodulatedSignal.Text = 'Demodulated signal';
            app.Simulate = uibutton(app.UIFigure, 'push');
            app.Simulate.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.Simulate.Text = 'Simulate';
            app.SpectrumButton = uibutton(app.UIFigure, 'push');
            app.SpectrumButton.ButtonPushedFcn = createCallbackFcn(app, @SpectrumButtonPushed, true);
            app.SpectrumButton.Text = 'Spectrum';
            app.MadebyPanel = uipanel(app.UIFigure);
            app.MadebyPanel.Title = 'Made by';
            app.PC35Label = uilabel(app.MadebyPanel);
            app.PC35Label.Text = 'PC35';
            app.PC33Label = uilabel(app.MadebyPanel);
            app.PC33Label.Text = 'PC33';
            app.PC41Label = uilabel(app.MadebyPanel);
            app.PC41Label.Text = 'PC41';
            app.PrakharSinghRajputLabel = uilabel(app.MadebyPanel);
            app.PrakharSinghRajputLabel.Text = 'Prakhar Singh Rajput';
            app.MayannkKumarSinghLabel = uilabel(app.MadebyPanel);
            app.MayannkKumarSinghLabel.Text = 'Mayannk Kumar Singh';
            app.AbhijeetTakleLabel = uilabel(app.MadebyPanel);
            app.AbhijeetTakleLabel.Text = 'Abhijeet Takle';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'logo.png';
            app.AnalogCommunicationLabel = uilabel(app.UIFigure);
            app.AnalogCommunicationLabel.Text = 'Analog Communication';
            app.GroupProjectLabel = uilabel(app.UIFigure);
            app.GroupProjectLabel.Text = 'Group Project';
            app.Trimester6Label = uilabel(app.UIFigure);
            app.Trimester6Label.Text = 'Trimester 6';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Project_1_DSBFC_Final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end